classdef NeuralBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadGroup                     matlab.ui.container.TabGroup
        ExtractdataTab                matlab.ui.container.Tab
        NeuralLoadButton              matlab.ui.control.Button
        SelectblocktoextractLabel     matlab.ui.control.Label
        NeuralBrowseButton            matlab.ui.control.Button
        NeuralpathEditFieldLabel      matlab.ui.control.Label
        NeuralPathEditField           matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        NeuralLamp                    matlab.ui.control.Lamp
        ExtractionandfilteringLabel   matlab.ui.control.Label
        NeuralGauge                   matlab.ui.control.LinearGauge
        LoaddataTab                   matlab.ui.container.Tab
        BehavLoadButton               matlab.ui.control.Button
        BehavBrowseButton             matlab.ui.control.Button
        BehaviouralLabel              matlab.ui.control.Label
        BehaviouralEditField          matlab.ui.control.EditField
        Lamp_3Label                   matlab.ui.control.Label
        BehaviouralLamp               matlab.ui.control.Lamp
        BlockDropDownLabel            matlab.ui.control.Label
        BlockDropDown                 matlab.ui.control.DropDown
        LoadingandPreprocessingLabel  matlab.ui.control.Label
        BlockGauge                    matlab.ui.control.LinearGauge
        ResetButton                   matlab.ui.control.Button
        EpochPreTimeEditFieldLabel    matlab.ui.control.Label
        EpochPreTime                  matlab.ui.control.NumericEditField
        EpochPostTimeEditFieldLabel   matlab.ui.control.Label
        EpochPostTime                 matlab.ui.control.NumericEditField
        SelectdataTab                 matlab.ui.container.Tab
        TrialTable                    matlab.ui.control.Table
        ChannelKnobLabel              matlab.ui.control.Label
        ChannelKnob                   matlab.ui.control.Knob
        TrialSliderLabel              matlab.ui.control.Label
        TrialSlider                   matlab.ui.control.Slider
        ViewGroup                     matlab.ui.container.TabGroup
        FilteredTraceTab              matlab.ui.container.Tab
        FTParamsPanel                 matlab.ui.container.Panel
        FilteredTraceAxes             matlab.ui.control.UIAxes
        FTStimAxes                    matlab.ui.control.UIAxes
        SpikesTab                     matlab.ui.container.Tab
        SpikeParamPanel               matlab.ui.container.Panel
        SpikeModeGroup                matlab.ui.container.ButtonGroup
        KButton                       matlab.ui.control.RadioButton
        GButton                       matlab.ui.control.RadioButton
        EvthreshSliderLabel           matlab.ui.control.Label
        EvThreshSlider                matlab.ui.control.Slider
        MedthreshLabel                matlab.ui.control.Label
        MedThreshSlider               matlab.ui.control.Slider
        RejthreshSliderLabel          matlab.ui.control.Label
        RejThreshSlider               matlab.ui.control.Slider
        SpikesAxes                    matlab.ui.control.UIAxes
        SpikesStimAxes                matlab.ui.control.UIAxes
        LFPTab                        matlab.ui.container.Tab
        LFPParamsPanel                matlab.ui.container.Panel
        GlobalmeannormSwitchLabel     matlab.ui.control.Label
        GMNSwitch                     matlab.ui.control.RockerSwitch
        LFPAxes                       matlab.ui.control.UIAxes
        LFPAxesStimLeft               matlab.ui.control.UIAxes
        LFPAxesStimRight              matlab.ui.control.UIAxes
        StimTab                       matlab.ui.container.Tab
        StimLAAxes                    matlab.ui.control.UIAxes
        StimLVAxes                    matlab.ui.control.UIAxes
        StimRVAxes                    matlab.ui.control.UIAxes
        StimRAAxes                    matlab.ui.control.UIAxes
        RasterTab                     matlab.ui.container.Tab
        RasterAxesStimRight           matlab.ui.control.UIAxes
        RasterAxesRaster              matlab.ui.control.UIAxes
        RasterAxesStimLeft            matlab.ui.control.UIAxes
        PSTHTab                       matlab.ui.container.Tab
        PSTHAxesStimRight             matlab.ui.control.UIAxes
        PSTHAxesPSTH                  matlab.ui.control.UIAxes
        PSTHAxesStimLeft              matlab.ui.control.UIAxes
        ParamtersRaster               matlab.ui.container.Panel
        SliderLabel                   matlab.ui.control.Label
        TimeBinSlider                 matlab.ui.control.Slider
    end

    
    properties (Access = private)
        behavPath % Temp storage for fullpath for behavioural file
        fs1 = 24414.0625 % TDT sampling rate
        fs2 = 1000 % Lfp resampled rate
        fs3 = 762.939453 % Sens, Sond, Valv rate
        fs4 = 48828.125 % Behavioural stim rate
        Trials % Behavioural trial data
        fEpoch % Processed neural data in memory
        lfpEpoch % Processed neural data in memory
        tvFEpoch % Time vector for plots, S
        tvLfpEpoch % Time vector for plots, S
        epochPreTime
        epochPostTime
        stims % Stim objects
        gCols =  parula(4) % Graph colours (stim)
        
    end
    
    methods (Access = private)
        
        function toggleSelectDataPanel(app, str)
            app.TrialTable.Enable = str;
            app.TrialSlider.Enable = str;
            app.ChannelKnob.Enable = str;
            % app.FiltSwitch.Enable = str;
            % app.PlotButton.Enable = str;
            
        end
        
        function updatePlots(app,n,c)
            % Clear and update plots
            % Clear all plots
            % Plot Filtered and LFP
            % (leave spikes for update button)
            
            plotFilt(app,n,c)
            plotLFP(app,n,c)
            
            [spikes, artThresh, eventThresh] = calcSpikes(app,n,c);
            plotSpikes(app, spikes, artThresh, eventThresh, n, c)
            
            plotStim(app,n,c);
            
            % And update current view
            ViewGroupSelectionChanged(app)
            
        end


        function plotFilt(app,n,c)
            plot(app.FilteredTraceAxes, app.tvFEpoch, app.fEpoch(:,c,n))
        end
        
        function plotStim(app, n, c)
            
            % Plot on Stim tab
            % No buffering
            
            
            cla(app.StimLAAxes)
            cla(app.StimRAAxes)
            cla(app.StimLVAxes)
            cla(app.StimRVAxes)
            
            
            TT = app.Trials.TT(n,:);
            if  TT==2 || TT==4 || TT==5
                
                % Generate
                stimLA = Church2(app.stims{1,1,n});
                % Plot
                plot(app.StimLAAxes, stimLA.sound, 'Color', app.gCols(1,:))
                
                stimRA = Church2(app.stims{1,2,n});
                plot(app.StimRAAxes, stimRA.sound, 'Color', app.gCols(1,:))
                
            else

            end
            
            
            if TT==3 || TT==4 || TT==5
                stimLV = Church2(app.stims{2,1,n});
                plot(app.StimLVAxes, stimLV.sound, 'Color', app.gCols(2,:))
                
                stimRV = Church2(app.stims{2,2,n});
                plot(app.StimRVAxes, stimRV.sound, 'Color', app.gCols(2,:))
            else

            end
            
            
        end
        
        function plotLFP(app, n, c)
            switch app.GMNSwitch.Value
                case 'On'
                    norm = true;
                case 'Off'
                    norm = false;
            end
            
            plotData = app.lfpEpoch(:,c,n);
            
            if norm
                plotData = plotData - int16(mean(app.lfpEpoch(:,~((1:size(app.lfpEpoch,2))==c),n), 2));
            end
            
            % Plot LFP
            plot(app.LFPAxes, app.tvLfpEpoch, plotData)
            
            % Plot stim on this tab
            plotStimOnAxes(app, app.LFPAxesStimLeft, app.LFPAxesStimRight, n) 
            
        end
        
        function [spikes, artThresh, eventThresh] = calcSpikes(app,n,c)
            % Calculate spikes using paramters, plot
            
            % Get channel and n
            % c = round(app.ChannelKnob.Value);
            % n = round(app.TrialSlider.Value);
            
            % Get method using selected method
            switch app.KButton.Value
                case 1
                    method = @NeuralPP.eventDetectK;
                    
                    params.medThresh = round(app.MedThreshSlider.Value);
                    params.artThresh = round(app.RejThreshSlider.Value);
                    params.eventThresh = round(app.EvThreshSlider.Value);
                    params.plotOn = false;
                    [spikes, artThresh] = method(app.fEpoch(:,c,n), params);
                    
                    eventThresh = params.eventThresh;
                    
                    
                case 0
                    params.thresh = [3 40];
                    params.reject = [5 50];
                    params.plotOn = false;
                    method = @NeuralPP.eventDetectG;
                    spikes = method(app.fEpoch(:,c,n), params);
                    
                    artThresh = 0;
                    eventThresh = params.thresh(3);
            end
            
        end
        
        function plotSpikes(app, spikes, artThresh, eventThresh, n, c)
            
            hold(app.SpikesAxes, 'off')
            plot(app.SpikesAxes, app.tvFEpoch, app.fEpoch(:,c,n), 'Color', [0.3, 0.3, 0.8])
            hold(app.SpikesAxes, 'on')
            plot(app.SpikesAxes, app.tvFEpoch, 0.5*spikes*max(app.fEpoch(:,c,n)), 'Color', [0.8, 0.1, 0.1])
            line(app.SpikesAxes, [min(app.tvFEpoch), max(app.tvFEpoch)], [eventThresh, eventThresh], 'Color', 'k')
            line(app.SpikesAxes, [min(app.tvFEpoch), max(app.tvFEpoch)], [artThresh, artThresh], 'Color', 'k')
            
            plotStimOnAxes(app, app.SpikesStimAxes, app.SpikesStimAxes, n) 
        end
        
        function [PSTH, tVec] = calcPSTH(app, raster)
            binSize = round(app.TimeBinSlider.Value);
            
            [PSTH, ~] = NeuralAnalysis.PSTH(raster, app.fs1, binSize);
            
            % Generate tVec for this length of PSTH
            tVec = linspace(app.EpochPreTime.Value, app.EpochPostTime.Value, length(PSTH));
        end
        
        function plotPSTH(app, tVec, PSTH, n, c)
            
            % Plot PSTH
            plot(app.PSTHAxesPSTH, tVec, PSTH);
            
            % Plot stim on this tab
            plotStimOnAxes(app, app.PSTHAxesStimLeft, app.PSTHAxesStimRight, n) 
            
        end

        function plotStimOnAxes(app, leftAxes, rightAxes, n) 


            % Get times for alignmet
            preTime = app.EpochPreTime.Value;
            postTime = app.EpochPostTime.Value;
            
            cla(leftAxes)
            cla(rightAxes)
            hold(leftAxes, 'on')
            hold(rightAxes, 'on')
            
            
            TT = app.Trials.TT(n,:);
            
            leg = {};
            
             % If all to same axis
             % Adjust colours
            if leftAxes==rightAxes
                c1 = 1;
                c2 = 2;
                c3 = 3;
                c4 = 4;
            else
                c1 = 1;
                c2 = 1;
                c3 = 2;
                c4 = 2;
            end
            
            
            if TT==2 || TT==4 || TT==5
                % Plot top, left stim
                % Generate
                stimLA = Church2(app.stims{1,1,n});
                [yBuff, x] = NeuralAnalysis.bufferY(stimLA.sound, app.fs4, preTime, postTime, 0);
                % Plot
                plot(leftAxes, x, yBuff, 'Color', app.gCols(c1,:))
                
                % Plot bottom, right stim
                stimRA = Church2(app.stims{1,2,n});
                [yBuff, x] = NeuralAnalysis.bufferY(stimRA.sound, app.fs4, preTime, postTime, 0);
                plot(rightAxes, x, yBuff, 'Color', app.gCols(c2,:))
                
                leg = [leg, 'Auditory'];
            end
            
            if TT==3 || TT==4 || TT==5
                
                stimLV = Church2(app.stims{2,1,n});
                [yBuff, x] = NeuralAnalysis.bufferY(stimLV.sound, app.fs4, preTime, postTime, 0);
                plot(leftAxes, x, yBuff, 'Color', app.gCols(c3,:))
                
                stimRV = Church2(app.stims{2,2,n});
                [yBuff, x] = NeuralAnalysis.bufferY(stimRV.sound, app.fs4, preTime, postTime, 0);
                plot(rightAxes, x, yBuff, 'Color', app.gCols(c4,:))
                leg = [leg, 'Visual'];
            end
            
            % If all to same axis
            % Adjust legend
            if leftAxes==rightAxes
                ll = ['L ']+string(leg)';
                lr = ['R ']+string(leg)';
                leg = [ll,lr];
                leg = [leg(1,:), leg(2,:)];
            end
            
            
            legend(leftAxes, leg)

        end
        
        
        function [raster, tVec] = calcRaster(app, spikes)
            
            [raster, tVec] = NeuralAnalysis.raster(spikes, app.fs1);
        end
        
        function plotRaster(app, raster, n, c)
            
            imagesc(app.RasterAxesRaster, double(raster));
            
            plotStimOnAxes(app, app.RasterAxesStimLeft, app.RasterAxesStimRight, n) 
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Populate available blocks in processing folder
            fn = [pwd, '\Preprocessing\*block*'];
            blocks = dir(fn);
            for b = 1:numel(blocks)
                app.BlockDropDown.Items{b} = blocks(b).name;
            end
            
        end

        % Button pushed function: NeuralLoadButton
        function NeuralLoadButtonPushed(app, event)
            % Extract specified directory to \Extracted\ using TDTHelper
            % Process using Neural
            
            % Reset lamp and gauge
            app.NeuralLoadButton.Enable = 'off';
            app.NeuralLamp.Color = [0.9, 0.9, 0.2];
            app.NeuralGauge.Value = 0;
            
            % Try and find folder in box
            fn = app.NeuralPathEditField.Value;
            
            % Run exist checks
            if ~exist(fn, 'dir')
                app.NeuralLamp.Color = [0.9, 0, 0];
                return
            end
            
            % Try and get block name
            fps = string(fn).split('\');
            block = fps(end);
            
            if block.lower().contains('block')
                % Assume valid dir
                fn = [fn, '\'];
            else
                app.NeuralLamp.Color = [0.9, 0, 0];
                return
            end
            
            % Generate an outPath
            outPath = [pwd, '\Extracted\', block.char(), '\'];
            
            % Create TDTHelper object
            TDT = TDTHelper(fn, outPath, false);
            ok = TDT.runExtraction();
            
            app.NeuralGauge.Value = 20;
            
            if ~ok
                app.NeuralLamp.Color = [0.9, 0, 0];
                return
            end
            
            % Begin processing using NeuralPP library
            % Filter
            % Save to \Preprecessing\Block
            
            path = [pwd, '\Preprocessing\', block.char(),'\'];
            if ~exist(path, 'dir')
                mkdir(path)
            end
            
            fn = [path, 'BB2.mat'];
            if exist(fn, 'file')
                % Skip BB2
                disp('BB2 already done, skipping.')
                app.NeuralGauge.Value = 60;
            else
                BB2 = TDT.loadEvID('BB_2');
                app.NeuralGauge.Value = 30;
                
                [fData, lfpData] = NeuralPP.filter(BB2, app.fs1);
                app.NeuralGauge.Value = 50;
                
                save(fn, 'fData', 'lfpData')
                app.NeuralGauge.Value = 60;
            end
            clear BB2
            
            fn = [path, 'BB3.mat'];
            if exist(fn, 'file')
                % Skip BB2
                disp('BB3 already done, skipping.')
                app.NeuralGauge.Value = 100;
            else
                BB3 = TDT.loadEvID('BB_3');
                app.NeuralGauge.Value = 70;
                
                [fData, lfpData] = NeuralPP.filter(BB3, app.fs1);
                app.NeuralGauge.Value = 90;
                
                save(fn, 'fData', 'lfpData')
                app.NeuralGauge.Value = 100;
                
            end
            clear BB3
            
            % Update lamp
            app.NeuralLamp.Color = [0, 0.9, 0];
            
            % Add block to available block list
            if ~all(string(app.BlockDropDown.Items).contains(block))
                % If not already in list
                nItems = numel(app.BlockDropDown.Items);
                app.BlockDropDown.Items{nItems} = block.char();
            end
        end

        % Button pushed function: NeuralBrowseButton
        function NeuralBrowseButtonPushed(app, event)
            fn = uigetdir;
            if ischar(fn)
                app.NeuralPathEditField.Value = fn;
            end
        end

        % Button pushed function: BehavBrowseButton
        function BehavBrowseButtonPushed(app, event)
            [fn, pn] = uigetfile;
            if ischar(fn)
                app.BehaviouralEditField.Value = fn;
                app.behavPath = pn;
            end
        end

        % Button pushed function: BehavLoadButton
        function BehavLoadButtonPushed(app, event)
            
            % Reset lamp and gauge
            app.BehavLoadButton.Enable = 'off';
            app.BehaviouralLamp.Color = [0.9, 0.9, 0.2];
            app.BlockGauge.Value = 0;
            toggleSelectDataPanel(app, 'off')
            
            % Check behavioural file matches block
            block = string(app.BlockDropDown.Value);
            bFn = string(app.BehaviouralEditField.Value);
            
            if ~bFn.contains(block)
                app.NeuralLamp.Color = [0.9, 0, 0];
            end
            
            % Load behavioural file
            data = load([app.behavPath, bFn.char()]);
            data = data.saveData(2:end);
            
            app.BlockGauge.Value = 10;
            
            % Do basic processing of behav data to table
            nTrials = numel(data);
            vars = {NaN(nTrials,1), 'TT', ''; ...
                NaN(nTrials,1), 'repeatMode', ''; ...
                NaN(nTrials,1), 'side', ''; ...
                NaN(nTrials,1), 'resp', ''; ...
                NaN(nTrials,1), 'aNoise', ''; ...
                NaN(nTrials,1), 'vNoise', ''; ...
                NaN(nTrials,1), 'gap1', ''; ...
                NaN(nTrials,1), 'gap2', ''; ...
                NaN(nTrials,1), 'duration', ''; ...
                NaN(nTrials,1), 'startTrialTime', ''; ...
                NaN(nTrials,1), 'aysncOffset', 'Note typo'; ...
                cell(nTrials,1), 'eventType', ''; ...
                NaN(nTrials,2), 'speakers', ''};
            nVars = size(vars,1);
            disp(vars)
            
            
            trials = table(vars{:,1});
            trials.Properties.VariableNames = vars(:,2);
            trials.Properties.VariableDescriptions = vars(:,3);
            stimObjects = cell(2,2,nTrials);
            
            for n = 1:nTrials
                disp(n)
                for v = 1:nVars
                    if isa(data{n}.(vars{v,2}), 'char')
                        trials.(vars{v,2}){n} = data{n}.(vars{v,2});
                    else
                        trials.(vars{v,2})(n,:) = data{n}.(vars{v,2});
                    end
                end
                
                % Collet stim objects to save in app.stims
                stimObjects(:,:,n) = data{n}.stimRecord;
            end
            
            app.stims = stimObjects;
            clear stimObjects
            
            % Add correct column
            trials.correct = trials.side==trials.resp;
            % And save to app
            app.Trials = trials;
            
            app.BlockGauge.Value = 30;
            
            
            % Check if neural data already processed up to spikes
            pPath = [pwd, '\Preprocessing\'];
            if ~exist('pPath', 'dir')
                mkdir(pPath)
            end
            
            fn = [pPath, block.char(), '\Epoched.mat'];
            params.EpochPreTime = app.EpochPreTime.Value;
            params.EpochPostTime = app.EpochPostTime.Value;
            if ~exist(fn, 'file')
                % If not, load processed data
                
                % BB2
                BB2 = load([pPath, block.char(), '\BB2.mat']);
                % Epoch
                fEpochBB2 = NeuralPP.epochData(params, trials.startTrialTime, BB2.fData, app.fs1);
                lfpEpochBB2 = NeuralPP.epochData(params, trials.startTrialTime, BB2.lfpData, app.fs2);
                
                app.BlockGauge.Value = 40;
                % Clean
                fEpochBB2 = NeuralPP.clean(fEpochBB2);
                app.BlockGauge.Value = 60;
                
                clear BB2
                
                % BB3
                BB3 = load([pPath, block.char(), '\BB3.mat']);
                % Epoch
                
                fEpochBB3 = NeuralPP.epochData(params, trials.startTrialTime, BB3.fData, app.fs1);
                lfpEpochBB3 = NeuralPP.epochData(params, trials.startTrialTime, BB3.lfpData, app.fs2);
                app.BlockGauge.Value = 70;
                % Clean
                fEpochBB3 = NeuralPP.clean(fEpochBB3);
                app.BlockGauge.Value = 90;
                
                clear BB3
                
                
                % Concatenate BB2 and BB3
                fEpoch = [fEpochBB2, fEpochBB3];
                clear fEpochBB2 fEpochBB3
                lfpEpoch = [lfpEpochBB2, lfpEpochBB3];
                clear lfpEpochBB2 lfpEpochBB3
                
                save(fn, 'fEpoch', 'lfpEpoch')
                app.BlockGauge.Value = 100;
                
            else
                % Load existing file
                load(fn);
                app.BlockGauge.Value = 100;
            end
            
            
            app.tvFEpoch = linspace(params.EpochPreTime, params.EpochPostTime, size(fEpoch,1));
            app.tvLfpEpoch = linspace(params.EpochPreTime, params.EpochPostTime, size(lfpEpoch,1));
            
            app.fEpoch = fEpoch;
            app.lfpEpoch = lfpEpoch;
            
            app.BehaviouralLamp.Color = [0, 0.9, 0];
            
            % Update trial viewer
            
            app.TrialSlider.Limits = [1, nTrials];
            TrialSliderValueChanged(app, event)
            
            % Turn on channel knob
            toggleSelectDataPanel(app, 'on')
            app.BehavLoadButton.Enable = 'on';
            
        end

        % Value changed function: ChannelKnob
        function ChannelKnobValueChanged(app, event)
            value = round(app.ChannelKnob.Value);
            
            app.ChannelKnob.MajorTickLabels = {'1', '32', '63'};
            app.ChannelKnob.MajorTicks = [1, 32, 63];
            if value>1 && value<32
                app.ChannelKnob.MajorTickLabels = {'1', num2str(value), '32', '63'};
                app.ChannelKnob.MajorTicks = [1, value, 32, 63];
            end
            
           ViewGroupSelectionChanged(app, event)  
            
        end

        % Value changed function: TrialSlider
        function TrialSliderValueChanged(app, event)
            trials = app.Trials;
            
            % Get selected trial
            n = round(app.TrialSlider.Value);
            
            % Run through columns in trials and convert and non-[1, 1] to multiple columns
            m = width(trials);
            dropList = {};
            newTable = table();
            for c = 1:m
                
                m2 = size(trials{:,c},2);
                if m2>1
                    dropList = [dropList, trials.Properties.VariableNames{c}];
                    for c2 = 1:m2
                        newName = [trials.Properties.VariableNames{c}, '_', num2str(c2)];
                        
                        newTable.(newName) = trials{:,c}(:,c2);
                    end
                end
                
                
            end

            trials(:,dropList) = [];
            trials = [trials, newTable];
            
            app.TrialTable.Data = table2cell(trials(n,:));
            app.TrialTable.ColumnName = trials.Properties.VariableNames;
            
            ViewGroupSelectionChanged(app, event)
            
            
        end

        % Value changing function: ChannelKnob
        function ChannelKnobValueChanging(app, event)
            
            
        end

        % Callback function
        function CalculateButtonPushed(app, event)


        end

        % Value changed function: GMNSwitch
        function GMNSwitchValueChanged(app, event)
            % plotLFP(app, round(app.TrialSlider.Value), round(app.ChannelKnob.Value));
            
        end

        % Selection change function: ViewGroup
        function ViewGroupSelectionChanged(app, event)
            selectedTab = app.ViewGroup.SelectedTab;
            n = round(app.TrialSlider.Value);
            c = round(app.ChannelKnob.Value);
            
            switch selectedTab.Title
                case 'Filtered trace'
                    plotFilt(app,n,c)
                case 'Spikes'
                    [spikes, artThresh, eventThresh] = calcSpikes(app,n,c);
                    plotSpikes(app, spikes, artThresh, eventThresh, n, c);
                    
                case 'LFP'
                    plotLFP(app, n, c);
                    
                case 'Stim'
                    plotStim(app, n, c);
                    
                case 'Raster'
                    spikes = calcSpikes(app, n, c);
                    raster = calcRaster(app, spikes);
                    plotRaster(app, raster, n, c);
                    
                case 'PSTH'
                    [spikes, artThresh, eventThresh] = calcSpikes(app, n, c);
                    plotSpikes(app, spikes, artThresh, eventThresh, n, c);
                    raster = calcRaster(app, spikes);
                    [PSTH, tVec] = calcPSTH(app, raster);
                    plotPSTH(app, tVec, PSTH, n,c);
                    
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           app.BehavLoadButton.Enable = 'On'; 
           app.NeuralLoadButton.Enable = 'On'; 
        end

        % Value changed function: EvThreshSlider
        function EvThreshSliderValueChanged(app, event)
            value = app.EvThreshSlider.Value;
            ViewGroupSelectionChanged(app, event)
            
        end

        % Value changed function: RejThreshSlider
        function RejThreshSliderValueChanged(app, event)
            value = app.RejThreshSlider.Value;
            ViewGroupSelectionChanged(app, event)
            
        end

        % Value changed function: MedThreshSlider
        function MedThreshSliderValueChanged(app, event)
            value = app.MedThreshSlider.Value;
            ViewGroupSelectionChanged(app, event)
            
        end

        % Value changed function: TimeBinSlider
        function TimeBinSliderValueChanged(app, event)
            value = app.TimeBinSlider.Value;
            ViewGroupSelectionChanged(app, event)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LoadGroup = uitabgroup(app.UIFigure);
            app.ExtractdataTab = uitab(app.LoadGroup);
            app.ExtractdataTab.Title = 'Extract data';
            app.ExtractdataTab.Units = 'pixels';
            app.NeuralLoadButton = uibutton(app.ExtractdataTab, 'push');
            app.NeuralLoadButton.ButtonPushedFcn = createCallbackFcn(app, @NeuralLoadButtonPushed, true);
            app.NeuralLoadButton.Text = 'Extract and filter';
            app.SelectblocktoextractLabel = uilabel(app.ExtractdataTab);
            app.SelectblocktoextractLabel.Text = 'Select block to extract';
            app.NeuralBrowseButton = uibutton(app.ExtractdataTab, 'push');
            app.NeuralBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @NeuralBrowseButtonPushed, true);
            app.NeuralBrowseButton.Text = ':';
            app.NeuralpathEditFieldLabel = uilabel(app.ExtractdataTab);
            app.NeuralpathEditFieldLabel.Text = 'Neural path';
            app.NeuralPathEditField = uieditfield(app.ExtractdataTab, 'text');
            app.Label = uilabel(app.ExtractdataTab);
            app.Label.Text = '';
            app.NeuralLamp = uilamp(app.ExtractdataTab);
            app.ExtractionandfilteringLabel = uilabel(app.ExtractdataTab);
            app.ExtractionandfilteringLabel.Text = 'Extraction and filtering';
            app.NeuralGauge = uigauge(app.ExtractdataTab, 'linear');
            app.LoaddataTab = uitab(app.LoadGroup);
            app.LoaddataTab.Title = 'Load data';
            app.LoaddataTab.Units = 'pixels';
            app.BehavLoadButton = uibutton(app.LoaddataTab, 'push');
            app.BehavLoadButton.ButtonPushedFcn = createCallbackFcn(app, @BehavLoadButtonPushed, true);
            app.BehavLoadButton.Text = 'Load and PP';
            app.BehavBrowseButton = uibutton(app.LoaddataTab, 'push');
            app.BehavBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BehavBrowseButtonPushed, true);
            app.BehavBrowseButton.Text = ':';
            app.BehaviouralLabel = uilabel(app.LoaddataTab);
            app.BehaviouralLabel.Text = 'Behavioural ';
            app.BehaviouralEditField = uieditfield(app.LoaddataTab, 'text');
            app.Lamp_3Label = uilabel(app.LoaddataTab);
            app.Lamp_3Label.Text = '';
            app.BehaviouralLamp = uilamp(app.LoaddataTab);
            app.BlockDropDownLabel = uilabel(app.LoaddataTab);
            app.BlockDropDownLabel.Text = 'Block';
            app.BlockDropDown = uidropdown(app.LoaddataTab);
            app.BlockDropDown.Items = {};
            app.BlockDropDown.Value = {};
            app.LoadingandPreprocessingLabel = uilabel(app.LoaddataTab);
            app.LoadingandPreprocessingLabel.Text = 'Loading and Preprocessing';
            app.BlockGauge = uigauge(app.LoaddataTab, 'linear');
            app.ResetButton = uibutton(app.LoaddataTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EpochPreTimeEditFieldLabel = uilabel(app.LoaddataTab);
            app.EpochPreTimeEditFieldLabel.Text = 'EpochPreTime';
            app.EpochPreTime = uieditfield(app.LoaddataTab, 'numeric');
            app.EpochPreTime.Limits = [-10 0];
            app.EpochPreTime.Editable = 'off';
            app.EpochPreTime.Value = -2;
            app.EpochPostTimeEditFieldLabel = uilabel(app.LoaddataTab);
            app.EpochPostTimeEditFieldLabel.Text = 'EpochPostTime';
            app.EpochPostTime = uieditfield(app.LoaddataTab, 'numeric');
            app.EpochPostTime.Limits = [0 10];
            app.EpochPostTime.Editable = 'off';
            app.EpochPostTime.Value = 1;
            app.SelectdataTab = uitab(app.LoadGroup);
            app.SelectdataTab.Title = 'Select data';
            app.SelectdataTab.Units = 'pixels';
            app.TrialTable = uitable(app.SelectdataTab);
            app.TrialTable.ColumnName = '';
            app.TrialTable.RowName = {};
            app.TrialTable.Enable = 'off';
            app.ChannelKnobLabel = uilabel(app.SelectdataTab);
            app.ChannelKnobLabel.Enable = 'off';
            app.ChannelKnobLabel.Text = 'Channel';
            app.ChannelKnob = uiknob(app.SelectdataTab, 'continuous');
            app.ChannelKnob.Limits = [1 32];
            app.ChannelKnob.MajorTicks = [1 32 63];
            app.ChannelKnob.ValueChangedFcn = createCallbackFcn(app, @ChannelKnobValueChanged, true);
            app.ChannelKnob.ValueChangingFcn = createCallbackFcn(app, @ChannelKnobValueChanging, true);
            app.ChannelKnob.Enable = 'off';
            app.ChannelKnob.Value = 1;
            app.TrialSliderLabel = uilabel(app.SelectdataTab);
            app.TrialSliderLabel.Enable = 'off';
            app.TrialSliderLabel.Text = 'Trial';
            app.TrialSlider = uislider(app.SelectdataTab);
            app.TrialSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.TrialSlider.ValueChangedFcn = createCallbackFcn(app, @TrialSliderValueChanged, true);
            app.TrialSlider.Enable = 'off';
            app.ViewGroup = uitabgroup(app.UIFigure);
            app.ViewGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewGroupSelectionChanged, true);
            app.FilteredTraceTab = uitab(app.ViewGroup);
            app.FilteredTraceTab.Title = 'Filtered trace';
            app.FilteredTraceTab.Units = 'pixels';
            app.FTParamsPanel = uipanel(app.FilteredTraceTab);
            app.FTParamsPanel.Title = 'Parameters';
            app.FilteredTraceAxes = uiaxes(app.FilteredTraceTab);
            title(app.FilteredTraceAxes, 'Filtered Trace');
            xlabel(app.FilteredTraceAxes, 'Time');
            ylabel(app.FilteredTraceAxes, 'Volts');
            app.FTStimAxes = uiaxes(app.FilteredTraceTab);
            title(app.FTStimAxes, 'Title');
            ylabel(app.FTStimAxes, 'Y');
            app.SpikesTab = uitab(app.ViewGroup);
            app.SpikesTab.Title = 'Spikes';
            app.SpikesTab.Units = 'pixels';
            app.SpikeParamPanel = uipanel(app.SpikesTab);
            app.SpikeParamPanel.Title = 'Parameters';
            app.SpikeModeGroup = uibuttongroup(app.SpikeParamPanel);
            app.SpikeModeGroup.Title = 'Mode';
            app.KButton = uiradiobutton(app.SpikeModeGroup);
            app.KButton.Text = 'K';
            app.KButton.Value = true;
            app.GButton = uiradiobutton(app.SpikeModeGroup);
            app.GButton.Enable = 'off';
            app.GButton.Text = 'G';
            app.EvthreshSliderLabel = uilabel(app.SpikeParamPanel);
            app.EvthreshSliderLabel.Enable = 'off';
            app.EvthreshSliderLabel.Text = 'Ev thresh';
            app.EvThreshSlider = uislider(app.SpikeParamPanel);
            app.EvThreshSlider.Limits = [1 20];
            app.EvThreshSlider.ValueChangedFcn = createCallbackFcn(app, @EvThreshSliderValueChanged, true);
            app.EvThreshSlider.Enable = 'off';
            app.EvThreshSlider.Value = 1;
            app.MedthreshLabel = uilabel(app.SpikeParamPanel);
            app.MedthreshLabel.Text = 'Med thresh';
            app.MedThreshSlider = uislider(app.SpikeParamPanel);
            app.MedThreshSlider.Limits = [1 20];
            app.MedThreshSlider.ValueChangedFcn = createCallbackFcn(app, @MedThreshSliderValueChanged, true);
            app.MedThreshSlider.Value = 3;
            app.RejthreshSliderLabel = uilabel(app.SpikeParamPanel);
            app.RejthreshSliderLabel.Text = 'Rej thresh';
            app.RejThreshSlider = uislider(app.SpikeParamPanel);
            app.RejThreshSlider.Limits = [1 20];
            app.RejThreshSlider.ValueChangedFcn = createCallbackFcn(app, @RejThreshSliderValueChanged, true);
            app.RejThreshSlider.Value = 10;
            app.SpikesAxes = uiaxes(app.SpikesTab);
            title(app.SpikesAxes, 'Spikes');
            xlabel(app.SpikesAxes, 'Time');
            ylabel(app.SpikesAxes, 'Volts');
            app.SpikesStimAxes = uiaxes(app.SpikesTab);
            title(app.SpikesStimAxes, 'Stims');
            ylabel(app.SpikesStimAxes, 'Mag');
            app.LFPTab = uitab(app.ViewGroup);
            app.LFPTab.Title = 'LFP';
            app.LFPTab.Units = 'pixels';
            app.LFPParamsPanel = uipanel(app.LFPTab);
            app.LFPParamsPanel.Title = 'Parameters';
            app.GlobalmeannormSwitchLabel = uilabel(app.LFPParamsPanel);
            app.GlobalmeannormSwitchLabel.Text = 'Global mean norm.';
            app.GMNSwitch = uiswitch(app.LFPParamsPanel, 'rocker');
            app.GMNSwitch.ValueChangedFcn = createCallbackFcn(app, @GMNSwitchValueChanged, true);
            app.LFPAxes = uiaxes(app.LFPTab);
            title(app.LFPAxes, 'LFP');
            ylabel(app.LFPAxes, 'Volts');
            app.LFPAxesStimLeft = uiaxes(app.LFPTab);
            title(app.LFPAxesStimLeft, 'Stim left');
            ylabel(app.LFPAxesStimLeft, 'Mag');
            app.LFPAxesStimRight = uiaxes(app.LFPTab);
            title(app.LFPAxesStimRight, 'Stim Right');
            xlabel(app.LFPAxesStimRight, 'Time');
            ylabel(app.LFPAxesStimRight, 'Mag');
            app.StimTab = uitab(app.ViewGroup);
            app.StimTab.Title = 'Stim';
            app.StimTab.Units = 'pixels';
            app.StimLAAxes = uiaxes(app.StimTab);
            title(app.StimLAAxes, 'Left: Aud');
            xlabel(app.StimLAAxes, 'Time');
            ylabel(app.StimLAAxes, 'Mag');
            app.StimLVAxes = uiaxes(app.StimTab);
            title(app.StimLVAxes, 'Left: Vis');
            xlabel(app.StimLVAxes, 'Time');
            ylabel(app.StimLVAxes, 'Mag');
            app.StimRVAxes = uiaxes(app.StimTab);
            title(app.StimRVAxes, 'Right: Vis');
            xlabel(app.StimRVAxes, 'Time');
            ylabel(app.StimRVAxes, 'Mag');
            app.StimRAAxes = uiaxes(app.StimTab);
            title(app.StimRAAxes, 'Right: Aud');
            xlabel(app.StimRAAxes, 'Time');
            ylabel(app.StimRAAxes, 'Mag');
            app.RasterTab = uitab(app.ViewGroup);
            app.RasterTab.Title = 'Raster';
            app.RasterTab.Units = 'pixels';
            app.RasterAxesStimRight = uiaxes(app.RasterTab);
            title(app.RasterAxesStimRight, 'Stim right');
            xlabel(app.RasterAxesStimRight, 'Time');
            ylabel(app.RasterAxesStimRight, 'Mag');
            app.RasterAxesRaster = uiaxes(app.RasterTab);
            title(app.RasterAxesRaster, 'Single trial raster');
            ylabel(app.RasterAxesRaster, 'Volts');
            app.RasterAxesStimLeft = uiaxes(app.RasterTab);
            title(app.RasterAxesStimLeft, 'Stim left');
            ylabel(app.RasterAxesStimLeft, 'Mag');
            app.PSTHTab = uitab(app.ViewGroup);
            app.PSTHTab.Title = 'PSTH';
            app.PSTHTab.Units = 'pixels';
            app.PSTHAxesStimRight = uiaxes(app.PSTHTab);
            title(app.PSTHAxesStimRight, 'Stim right');
            xlabel(app.PSTHAxesStimRight, 'Time');
            ylabel(app.PSTHAxesStimRight, 'Mag');
            app.PSTHAxesPSTH = uiaxes(app.PSTHTab);
            title(app.PSTHAxesPSTH, 'Single trial PSTH');
            ylabel(app.PSTHAxesPSTH, 'nSpikes');
            app.PSTHAxesStimLeft = uiaxes(app.PSTHTab);
            title(app.PSTHAxesStimLeft, 'Stim left');
            ylabel(app.PSTHAxesStimLeft, 'Mag');
            app.ParamtersRaster = uipanel(app.PSTHTab);
            app.ParamtersRaster.Title = 'Panel';
            app.SliderLabel = uilabel(app.ParamtersRaster);
            app.SliderLabel.Text = 'Slider';
            app.TimeBinSlider = uislider(app.ParamtersRaster);
            app.TimeBinSlider.Limits = [1 30];
            app.TimeBinSlider.ValueChangedFcn = createCallbackFcn(app, @TimeBinSliderValueChanged, true);
            app.TimeBinSlider.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = NeuralBrowser()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end