classdef SpatialNeuralBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadGroup                     matlab.ui.container.TabGroup
        ExtractdataTab                matlab.ui.container.Tab
        NeuralLoadButton              matlab.ui.control.Button
        SelectblocktoextractLabel     matlab.ui.control.Label
        NeuralBrowseButton            matlab.ui.control.Button
        NeuralpathEditFieldLabel      matlab.ui.control.Label
        NeuralPathEditField           matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        NeuralLamp                    matlab.ui.control.Lamp
        ExtractionandfilteringLabel   matlab.ui.control.Label
        NeuralGauge                   matlab.ui.control.LinearGauge
        MemCheckBox                   matlab.ui.control.CheckBox
        LoaddataTab                   matlab.ui.container.Tab
        BehavLoadButton               matlab.ui.control.Button
        BehavBrowseButton             matlab.ui.control.Button
        BehaviouralLabel              matlab.ui.control.Label
        BehaviouralEditField          matlab.ui.control.EditField
        Lamp_3Label                   matlab.ui.control.Label
        BehaviouralLamp               matlab.ui.control.Lamp
        BlockDropDownLabel            matlab.ui.control.Label
        BlockDropDown                 matlab.ui.control.DropDown
        LoadingandPreprocessingLabel  matlab.ui.control.Label
        BlockGauge                    matlab.ui.control.LinearGauge
        ResetButton                   matlab.ui.control.Button
        EpochPreTimeEditFieldLabel    matlab.ui.control.Label
        EpochPreTime                  matlab.ui.control.NumericEditField
        EpochPostTimeEditFieldLabel   matlab.ui.control.Label
        EpochPostTime                 matlab.ui.control.NumericEditField
        SelectdataTab                 matlab.ui.container.Tab
        TrialTable                    matlab.ui.control.Table
        ChannelKnobLabel              matlab.ui.control.Label
        ChannelKnob                   matlab.ui.control.Knob
        TrialSliderLabel              matlab.ui.control.Label
        TrialSlider                   matlab.ui.control.Slider
        ViewGroup                     matlab.ui.container.TabGroup
        FilteredTraceTab              matlab.ui.container.Tab
        FTParamsPanel                 matlab.ui.container.Panel
        FilteredTraceAxes             matlab.ui.control.UIAxes
        FTStimAxes                    matlab.ui.control.UIAxes
        SpikesTab                     matlab.ui.container.Tab
        SpikeParamPanel               matlab.ui.container.Panel
        SpikeModeGroup                matlab.ui.container.ButtonGroup
        KButton                       matlab.ui.control.RadioButton
        GButton                       matlab.ui.control.RadioButton
        EvthreshSliderLabel           matlab.ui.control.Label
        EvThreshSlider                matlab.ui.control.Slider
        MedthreshLabel                matlab.ui.control.Label
        MedThreshSlider               matlab.ui.control.Slider
        RejthreshSliderLabel          matlab.ui.control.Label
        RejThreshSlider               matlab.ui.control.Slider
        SpikesAxes                    matlab.ui.control.UIAxes
        SpikesStimAxes                matlab.ui.control.UIAxes
        LFPTab                        matlab.ui.container.Tab
        LFPParamsPanel                matlab.ui.container.Panel
        GlobalmeannormSwitchLabel     matlab.ui.control.Label
        GMNSwitch                     matlab.ui.control.RockerSwitch
        LFPAxes                       matlab.ui.control.UIAxes
        LFPAxesStimLeft               matlab.ui.control.UIAxes
        LFPAxesStimRight              matlab.ui.control.UIAxes
        StimTab                       matlab.ui.container.Tab
        StimLAAxes                    matlab.ui.control.UIAxes
        StimLVAxes                    matlab.ui.control.UIAxes
        StimRVAxes                    matlab.ui.control.UIAxes
        StimRAAxes                    matlab.ui.control.UIAxes
        RasterTab                     matlab.ui.container.Tab
        RasterAxesStimRight           matlab.ui.control.UIAxes
        RasterAxesRaster              matlab.ui.control.UIAxes
        RasterAxesStimLeft            matlab.ui.control.UIAxes
        PSTHTab                       matlab.ui.container.Tab
        PSTHAxesStimRight             matlab.ui.control.UIAxes
        PSTHAxesPSTH                  matlab.ui.control.UIAxes
        PSTHAxesStimLeft              matlab.ui.control.UIAxes
        ParamtersRaster               matlab.ui.container.Panel
        SliderLabel                   matlab.ui.control.Label
        TimeBinSlider                 matlab.ui.control.Slider
    end

    
    properties (Access = public)
        behavPath % Temp storage for fullpath for behavioural file
        fs1 = 24414.0625 % TDT sampling rate
        fs2 = 1000 % Lfp resampled rate
        fs3 = 762.939453 % Sens, Sond, Valv rate
        fs4 = 48828.125 % Behavioural stim rate
        Trials % Behavioural trial data
        fEpoch % Processed neural data in memory
        lfpEpoch % Processed neural data in memory
        tvFEpoch % Time vector for plots, S
        tvLfpEpoch % Time vector for plots, S
        epochPreTime
        epochPostTime
        stims % Stim objects
        gCols =  parula(4) % Graph colours (stim)
        
    end
    
    methods (Access = private)
        % See SpatialMethods
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app = SpatialMethods.startupFcn(app);
        end

        % Button pushed function: NeuralLoadButton
        function NeuralLoadButtonPushed(app, event)
            try
                app = SpatialMethods.NeuralLoadButtonPushed(app, event);
            catch err
                disp(err)
                app.NeuralLoadButton.Enable = 'on';
            end
           
        end

        % Button pushed function: NeuralBrowseButton
        function NeuralBrowseButtonPushed(app, event)
            app = SpatialMethods.NeuralBrowseButtonPushed(app, event);
        end

        % Button pushed function: BehavBrowseButton
        function BehavBrowseButtonPushed(app, event)
            app = SpatialMethods.BehavBrowseButtonPushed(app, event);
        end

        % Button pushed function: BehavLoadButton
        function BehavLoadButtonPushed(app, event)
            % try
                app = SpatialMethods.BehavLoadButtonPushed(app, event);
                
            % catch err
            %     disp(err)
            %     app.BehavLoadButton.Enable = 'on';
            %     keyboard
            % end
            
        end

        % Value changed function: ChannelKnob
        function ChannelKnobValueChanged(app, event)
           app = SpatialMethods.ChannelKnobValueChanged(app, event);
            
        end

        % Value changed function: TrialSlider
        function TrialSliderValueChanged(app, event)
            app = SpatialMethods.TrialSliderValueChanged(app, event);
           
        end

        % Value changing function: ChannelKnob
        function ChannelKnobValueChanging(app, event)
            
            
        end

        % Callback function
        function CalculateButtonPushed(app, event)
            
        end

        % Value changed function: GMNSwitch
        function GMNSwitchValueChanged(app, event)
            % plotLFP(app, round(app.TrialSlider.Value), round(app.ChannelKnob.Value));
            
        end

        % Selection change function: ViewGroup
        function ViewGroupSelectionChanged(app, event)
            
            app = SpatialMethods.ViewGroupSelectionChanged(app, event);
            
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           app.BehavLoadButton.Enable = 'On'; 
           app.NeuralLoadButton.Enable = 'On'; 
        end

        % Value changed function: EvThreshSlider
        function EvThreshSliderValueChanged(app, event)
            value = app.EvThreshSlider.Value;
            SpatialMethods.ViewGroupSelectionChanged(app, event)
            
        end

        % Value changed function: RejThreshSlider
        function RejThreshSliderValueChanged(app, event)
            value = app.RejThreshSlider.Value;
            SpatialMethods.ViewGroupSelectionChanged(app, event)
            
        end

        % Value changed function: MedThreshSlider
        function MedThreshSliderValueChanged(app, event)
            value = app.MedThreshSlider.Value;
            SpatialMethods.ViewGroupSelectionChanged(app, event)
            
        end

        % Value changed function: TimeBinSlider
        function TimeBinSliderValueChanged(app, event)
            app = SpatialMethods.ViewGroupSelectionChanged(app, event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LoadGroup = uitabgroup(app.UIFigure);
            app.ExtractdataTab = uitab(app.LoadGroup);
            app.ExtractdataTab.Title = 'Extract data';
            app.ExtractdataTab.Units = 'pixels';
            app.NeuralLoadButton = uibutton(app.ExtractdataTab, 'push');
            app.NeuralLoadButton.ButtonPushedFcn = createCallbackFcn(app, @NeuralLoadButtonPushed, true);
            app.NeuralLoadButton.Text = 'Extract and filter';
            app.SelectblocktoextractLabel = uilabel(app.ExtractdataTab);
            app.SelectblocktoextractLabel.Text = 'Select block to extract';
            app.NeuralBrowseButton = uibutton(app.ExtractdataTab, 'push');
            app.NeuralBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @NeuralBrowseButtonPushed, true);
            app.NeuralBrowseButton.Text = ':';
            app.NeuralpathEditFieldLabel = uilabel(app.ExtractdataTab);
            app.NeuralpathEditFieldLabel.Text = 'Neural path';
            app.NeuralPathEditField = uieditfield(app.ExtractdataTab, 'text');
            app.Label = uilabel(app.ExtractdataTab);
            app.Label.Text = '';
            app.NeuralLamp = uilamp(app.ExtractdataTab);
            app.ExtractionandfilteringLabel = uilabel(app.ExtractdataTab);
            app.ExtractionandfilteringLabel.Text = 'Extraction and filtering';
            app.NeuralGauge = uigauge(app.ExtractdataTab, 'linear');
            app.MemCheckBox = uicheckbox(app.ExtractdataTab);
            app.MemCheckBox.Text = 'Reduce memory usage';
            app.MemCheckBox.Value = true;
            app.LoaddataTab = uitab(app.LoadGroup);
            app.LoaddataTab.Title = 'Load data';
            app.LoaddataTab.Units = 'pixels';
            app.BehavLoadButton = uibutton(app.LoaddataTab, 'push');
            app.BehavLoadButton.ButtonPushedFcn = createCallbackFcn(app, @BehavLoadButtonPushed, true);
            app.BehavLoadButton.Text = 'Load and PP';
            app.BehavBrowseButton = uibutton(app.LoaddataTab, 'push');
            app.BehavBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BehavBrowseButtonPushed, true);
            app.BehavBrowseButton.Text = ':';
            app.BehaviouralLabel = uilabel(app.LoaddataTab);
            app.BehaviouralLabel.Text = 'Behavioural ';
            app.BehaviouralEditField = uieditfield(app.LoaddataTab, 'text');
            app.Lamp_3Label = uilabel(app.LoaddataTab);
            app.Lamp_3Label.Text = '';
            app.BehaviouralLamp = uilamp(app.LoaddataTab);
            app.BlockDropDownLabel = uilabel(app.LoaddataTab);
            app.BlockDropDownLabel.Text = 'Block';
            app.BlockDropDown = uidropdown(app.LoaddataTab);
            app.BlockDropDown.Items = {};
            app.BlockDropDown.Value = {};
            app.LoadingandPreprocessingLabel = uilabel(app.LoaddataTab);
            app.LoadingandPreprocessingLabel.Text = 'Loading and Preprocessing';
            app.BlockGauge = uigauge(app.LoaddataTab, 'linear');
            app.ResetButton = uibutton(app.LoaddataTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EpochPreTimeEditFieldLabel = uilabel(app.LoaddataTab);
            app.EpochPreTimeEditFieldLabel.Text = 'EpochPreTime';
            app.EpochPreTime = uieditfield(app.LoaddataTab, 'numeric');
            app.EpochPreTime.Limits = [-10 0];
            app.EpochPreTime.Editable = 'off';
            app.EpochPreTime.Value = -2;
            app.EpochPostTimeEditFieldLabel = uilabel(app.LoaddataTab);
            app.EpochPostTimeEditFieldLabel.Text = 'EpochPostTime';
            app.EpochPostTime = uieditfield(app.LoaddataTab, 'numeric');
            app.EpochPostTime.Limits = [0 10];
            app.EpochPostTime.Editable = 'off';
            app.EpochPostTime.Value = 1;
            app.SelectdataTab = uitab(app.LoadGroup);
            app.SelectdataTab.Title = 'Select data';
            app.SelectdataTab.Units = 'pixels';
            app.TrialTable = uitable(app.SelectdataTab);
            app.TrialTable.ColumnName = '';
            app.TrialTable.RowName = {};
            app.TrialTable.Enable = 'off';
            app.ChannelKnobLabel = uilabel(app.SelectdataTab);
            app.ChannelKnobLabel.Enable = 'off';
            app.ChannelKnobLabel.Text = 'Channel';
            app.ChannelKnob = uiknob(app.SelectdataTab, 'continuous');
            app.ChannelKnob.Limits = [1 32];
            app.ChannelKnob.MajorTicks = [1 32 63];
            app.ChannelKnob.ValueChangedFcn = createCallbackFcn(app, @ChannelKnobValueChanged, true);
            app.ChannelKnob.ValueChangingFcn = createCallbackFcn(app, @ChannelKnobValueChanging, true);
            app.ChannelKnob.Enable = 'off';
            app.ChannelKnob.Value = 1;
            app.TrialSliderLabel = uilabel(app.SelectdataTab);
            app.TrialSliderLabel.Enable = 'off';
            app.TrialSliderLabel.Text = 'Trial';
            app.TrialSlider = uislider(app.SelectdataTab);
            app.TrialSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.TrialSlider.ValueChangedFcn = createCallbackFcn(app, @TrialSliderValueChanged, true);
            app.TrialSlider.Enable = 'off';
            app.ViewGroup = uitabgroup(app.UIFigure);
            app.ViewGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewGroupSelectionChanged, true);
            app.FilteredTraceTab = uitab(app.ViewGroup);
            app.FilteredTraceTab.Title = 'Filtered trace';
            app.FilteredTraceTab.Units = 'pixels';
            app.FTParamsPanel = uipanel(app.FilteredTraceTab);
            app.FTParamsPanel.Title = 'Parameters';
            app.FilteredTraceAxes = uiaxes(app.FilteredTraceTab);
            title(app.FilteredTraceAxes, 'Filtered Trace');
            xlabel(app.FilteredTraceAxes, 'Time');
            ylabel(app.FilteredTraceAxes, 'Volts');
            app.FTStimAxes = uiaxes(app.FilteredTraceTab);
            title(app.FTStimAxes, 'Stim');
            ylabel(app.FTStimAxes, 'Y');
            app.SpikesTab = uitab(app.ViewGroup);
            app.SpikesTab.Title = 'Spikes';
            app.SpikesTab.Units = 'pixels';
            app.SpikeParamPanel = uipanel(app.SpikesTab);
            app.SpikeParamPanel.Title = 'Parameters';
            app.SpikeModeGroup = uibuttongroup(app.SpikeParamPanel);
            app.SpikeModeGroup.Title = 'Mode';
            app.KButton = uiradiobutton(app.SpikeModeGroup);
            app.KButton.Text = 'K';
            app.KButton.Value = true;
            app.GButton = uiradiobutton(app.SpikeModeGroup);
            app.GButton.Enable = 'off';
            app.GButton.Text = 'G';
            app.EvthreshSliderLabel = uilabel(app.SpikeParamPanel);
            app.EvthreshSliderLabel.Enable = 'off';
            app.EvthreshSliderLabel.Text = 'Ev thresh';
            app.EvThreshSlider = uislider(app.SpikeParamPanel);
            app.EvThreshSlider.Limits = [1 20];
            app.EvThreshSlider.ValueChangedFcn = createCallbackFcn(app, @EvThreshSliderValueChanged, true);
            app.EvThreshSlider.Enable = 'off';
            app.EvThreshSlider.Value = 1;
            app.MedthreshLabel = uilabel(app.SpikeParamPanel);
            app.MedthreshLabel.Text = 'Med thresh';
            app.MedThreshSlider = uislider(app.SpikeParamPanel);
            app.MedThreshSlider.Limits = [1 20];
            app.MedThreshSlider.ValueChangedFcn = createCallbackFcn(app, @MedThreshSliderValueChanged, true);
            app.MedThreshSlider.Value = 3;
            app.RejthreshSliderLabel = uilabel(app.SpikeParamPanel);
            app.RejthreshSliderLabel.Text = 'Rej thresh';
            app.RejThreshSlider = uislider(app.SpikeParamPanel);
            app.RejThreshSlider.Limits = [1 20];
            app.RejThreshSlider.ValueChangedFcn = createCallbackFcn(app, @RejThreshSliderValueChanged, true);
            app.RejThreshSlider.Value = 10;
            app.SpikesAxes = uiaxes(app.SpikesTab);
            title(app.SpikesAxes, 'Spikes');
            xlabel(app.SpikesAxes, 'Time');
            ylabel(app.SpikesAxes, 'Volts');
            app.SpikesStimAxes = uiaxes(app.SpikesTab);
            title(app.SpikesStimAxes, 'Stims');
            ylabel(app.SpikesStimAxes, 'Mag');
            app.LFPTab = uitab(app.ViewGroup);
            app.LFPTab.Title = 'LFP';
            app.LFPTab.Units = 'pixels';
            app.LFPParamsPanel = uipanel(app.LFPTab);
            app.LFPParamsPanel.Title = 'Parameters';
            app.GlobalmeannormSwitchLabel = uilabel(app.LFPParamsPanel);
            app.GlobalmeannormSwitchLabel.Enable = 'off';
            app.GlobalmeannormSwitchLabel.Text = 'Global mean norm.';
            app.GMNSwitch = uiswitch(app.LFPParamsPanel, 'rocker');
            app.GMNSwitch.ValueChangedFcn = createCallbackFcn(app, @GMNSwitchValueChanged, true);
            app.GMNSwitch.Enable = 'off';
            app.LFPAxes = uiaxes(app.LFPTab);
            title(app.LFPAxes, 'LFP');
            ylabel(app.LFPAxes, 'Volts');
            app.LFPAxesStimLeft = uiaxes(app.LFPTab);
            title(app.LFPAxesStimLeft, 'Stim left');
            ylabel(app.LFPAxesStimLeft, 'Mag');
            app.LFPAxesStimRight = uiaxes(app.LFPTab);
            title(app.LFPAxesStimRight, 'Stim Right');
            xlabel(app.LFPAxesStimRight, 'Time');
            ylabel(app.LFPAxesStimRight, 'Mag');
            app.StimTab = uitab(app.ViewGroup);
            app.StimTab.Title = 'Stim';
            app.StimTab.Units = 'pixels';
            app.StimLAAxes = uiaxes(app.StimTab);
            title(app.StimLAAxes, 'Left: Aud');
            xlabel(app.StimLAAxes, 'Time');
            ylabel(app.StimLAAxes, 'Mag');
            app.StimLVAxes = uiaxes(app.StimTab);
            title(app.StimLVAxes, 'Left: Vis');
            xlabel(app.StimLVAxes, 'Time');
            ylabel(app.StimLVAxes, 'Mag');
            app.StimRVAxes = uiaxes(app.StimTab);
            title(app.StimRVAxes, 'Right: Vis');
            xlabel(app.StimRVAxes, 'Time');
            ylabel(app.StimRVAxes, 'Mag');
            app.StimRAAxes = uiaxes(app.StimTab);
            title(app.StimRAAxes, 'Right: Aud');
            xlabel(app.StimRAAxes, 'Time');
            ylabel(app.StimRAAxes, 'Mag');
            app.RasterTab = uitab(app.ViewGroup);
            app.RasterTab.Title = 'Raster';
            app.RasterTab.Units = 'pixels';
            app.RasterAxesStimRight = uiaxes(app.RasterTab);
            title(app.RasterAxesStimRight, 'Stim right');
            xlabel(app.RasterAxesStimRight, 'Time');
            ylabel(app.RasterAxesStimRight, 'Mag');
            app.RasterAxesRaster = uiaxes(app.RasterTab);
            title(app.RasterAxesRaster, 'Single trial raster');
            ylabel(app.RasterAxesRaster, 'Volts');
            app.RasterAxesStimLeft = uiaxes(app.RasterTab);
            title(app.RasterAxesStimLeft, 'Stim left');
            ylabel(app.RasterAxesStimLeft, 'Mag');
            app.PSTHTab = uitab(app.ViewGroup);
            app.PSTHTab.Title = 'PSTH';
            app.PSTHTab.Units = 'pixels';
            app.PSTHAxesStimRight = uiaxes(app.PSTHTab);
            title(app.PSTHAxesStimRight, 'Stim right');
            xlabel(app.PSTHAxesStimRight, 'Time');
            ylabel(app.PSTHAxesStimRight, 'Mag');
            app.PSTHAxesPSTH = uiaxes(app.PSTHTab);
            title(app.PSTHAxesPSTH, 'Single trial PSTH');
            ylabel(app.PSTHAxesPSTH, 'nSpikes');
            app.PSTHAxesStimLeft = uiaxes(app.PSTHTab);
            title(app.PSTHAxesStimLeft, 'Stim left');
            ylabel(app.PSTHAxesStimLeft, 'Mag');
            app.ParamtersRaster = uipanel(app.PSTHTab);
            app.ParamtersRaster.Title = 'Panel';
            app.SliderLabel = uilabel(app.ParamtersRaster);
            app.SliderLabel.Text = 'Slider';
            app.TimeBinSlider = uislider(app.ParamtersRaster);
            app.TimeBinSlider.Limits = [1 30];
            app.TimeBinSlider.ValueChangedFcn = createCallbackFcn(app, @TimeBinSliderValueChanged, true);
            app.TimeBinSlider.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = SpatialNeuralBrowser()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end